classdef GUI_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UploadVideoButton             matlab.ui.control.Button
        FrameSliderLabel              matlab.ui.control.Label
        FrameSlider                   matlab.ui.control.Slider
        SaveProcessedVideoButton      matlab.ui.control.Button
        DisplayBoundaryLayerButton    matlab.ui.control.StateButton
        InstantaneousVelocityDisplayButton  matlab.ui.control.Button
        AverageVelocityDisplayButton  matlab.ui.control.Button
        MaxSpreadRadiusDisplayButton  matlab.ui.control.Button
        SaveAnalyticsButton           matlab.ui.control.Button
        SatelliteDropletSpeedDisplayButton  matlab.ui.control.Button
        SatelliteDropletCountDisplayButton  matlab.ui.control.Button
        PlayPauseButton               matlab.ui.control.Button
        InstantaneousVelocityUnits    matlab.ui.control.EditField
        AverageVelocityUnits          matlab.ui.control.EditField
        MaxSpreadUnits                matlab.ui.control.EditField
        DropletSpeedUnits             matlab.ui.control.EditField
        DropletCountUnits             matlab.ui.control.EditField
        CurrentFrameEditFieldLabel    matlab.ui.control.Label
        CurrentFrameEditField         matlab.ui.control.EditField
        InstantVelocityTxt            matlab.ui.control.NumericEditField
        AverageVelocityTxt            matlab.ui.control.NumericEditField
        MaxRadiusTxt                  matlab.ui.control.NumericEditField
        DropletSpeedTxt               matlab.ui.control.NumericEditField
        DropletCountTxt               matlab.ui.control.NumericEditField
        PixelsmmEditFieldLabel        matlab.ui.control.Label
        PixelsmmEditField             matlab.ui.control.NumericEditField
        ContactAngleDisplayButton     matlab.ui.control.Button
        ContactAngleUnits             matlab.ui.control.EditField
        ContactAngleTxt               matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        R % total frame count of original video
        B % matrix of processed border video frames with the floor removed
        L % matrix of original video frames with a color border
        DialogApp % Dialog box app
        floorheight % pixel height of floor
        floorangle % angle of floor
        mspread % max spread radius
        pxl_to_mm % pixel to mm ratio
        border_color % color of the outline on matrix L
        cangle % contact angle (both left and right) for the matrix
        fvelocity %fall velocity of droplet x and y directions
        impactdata % includes [speed,frame#] for impact of primary droplet
        totalobjects % a count of the number of separate objects in frame
    end
    
    properties (Access = public)
        M % matrix of orignal video frames
    end
    
    methods (Access = public)
        function updatefloor(app, h, t)
            % Store inputs as properties
            app.floorheight = h;
            app.floorangle = t;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadVideoButton
        function UploadVideoButtonPushed(app, event)
            [vidname,vidpath] = uigetfile('*.avi'); % this asks the user for a file input
            if vidname == 0
                figure(app.UIFigure); % this ensures that the app does not go to the background when uigetfile is called
                return; % this is a simple catch to stop the program from displaying an error if the user presses cancel
            end
            figure(app.UIFigure); % this ensures that the app does not go to the background when uigetfile is called
            fullname = fullfile(vidpath,vidname); % this concatenates the path and file name of the input file
            [app.M,app.R] = video2frame(fullname); 
            imshow(app.M(:,:,:,1),'Parent',app.UIAxes); % this displays the input file in the original video axes
            totalframes = app.R; % this sets totalframes to the number of frames in the uploaded video
            app.FrameSlider.Limits = [1 totalframes]; % this sets the slider limits to 1 to the total frames of the video
            app.FrameSlider.MajorTicks = [1:30:totalframes totalframes]; % this sets the slider major ticks to 1 to the total frames 
            % of the video in increments of 20
            app.FrameSlider.MinorTicks = [1:5:totalframes]; % this sets the slider minor ticks to 1 to the total frames 
            % of the video in increments of 5
            app.DialogApp = floorselectdlgbox(app,app.M);
            waitfor(app.DialogApp);
            B_withfloor = borders(app.M); % this processes the video to get a B&W border video
            app.B = floorremove(B_withfloor,app.floorheight,app.floorangle); % this processes the border video to remove the floor
            app.border_color = [255,255,0]; % this sets the default for border color to red
            app.L = outlines(app.M,app.B,app.floorangle,3,app.border_color); % this creates a matrix of frames with the original video
            % with a colored line around the border
            imshow(app.L(:,:,:,1),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            app.cangle = contactAngles(app.B,app.floorheight);
            % [app.fvelocity, app.impactdata, app.totalobjects] = fallVelocity(app.B,app.floorheight); %this optains the fall veloctiy of the droplet and jet in the x and y directions
            % app.mspread = sum(maxSpread(app.B)); % this processes the B&W border video to find the max spread radius
            % app.MaxRadiusTxt.Value = app.mspread;
        end

        % Button pushed function: InstantaneousVelocityDisplayButton
        function InstantaneousVelocityDisplayButtonPushed(app, event)
            if app.InstantVelocityTxt.Visible == 1
                app.InstantVelocityTxt.Visible = 'off'; % this hides the text box for instant velocity given it was visible
            else 
                app.InstantVelocityTxt.Visible = 'on'; % this displays the text box for instant velocity given it was not visible
            end
        end

        % Button pushed function: AverageVelocityDisplayButton
        function AverageVelocityDisplayButtonPushed(app, event)
            if app.AverageVelocityTxt.Visible == 1
                app.AverageVelocityTxt.Visible = 'off'; % this hides the text box for average velocity given it was visible
            else 
                app.AverageVelocityTxt.Visible = 'on'; % this displays the text box for average velocity given it was not visible
            end
        end

        % Button pushed function: MaxSpreadRadiusDisplayButton
        function MaxSpreadRadiusDisplayButtonPushed(app, event)
            if app.MaxRadiusTxt.Visible == 1
               app.MaxRadiusTxt.Visible = 'off'; % this hides the text box for max spread radius given it was visible
            else 
               app.MaxRadiusTxt.Visible = 'on'; % this displays the text box for max spread radius given it was not visible
            end
        end

        % Button pushed function: SatelliteDropletSpeedDisplayButton
        function SatelliteDropletSpeedDisplayButtonPushed(app, event)
            if app.DropletSpeedTxt.Visible == 1
               app.DropletSpeedTxt.Visible = 'off'; % this hides the text box for jet speed given it was visible
            else 
               app.DropletSpeedTxt.Visible = 'on'; % this displays the text box for jet speed given it was not visible
            end
        end

        % Button pushed function: SatelliteDropletCountDisplayButton
        function SatelliteDropletCountDisplayButtonPushed(app, event)
            if app.DropletCountTxt.Visible == 1
               app.DropletCountTxt.Visible = 'off'; % this hides the text box for droplet formation given it was visible
            else 
               app.DropletCountTxt.Visible = 'on'; % this displays the text box for droplet formation given it was not visible
            end
        end

        % Button pushed function: SaveAnalyticsButton
        function SaveAnalyticsButtonPushed(app, event)
            % Create two columns of data
            A  = rand(10,1);
            C = rand(10,1);
            % Create a table with the data and variable names
            T = table(A, C, 'VariableNames', { 'A', 'C'} );
            % Write data to text file
            [txtname,txtpath] = uiputfile('*.csv'); % this asks the user for a file destination
            fullpath = fullfile(txtpath,txtname); % this concatenates the path and file name of the file destination
            writetable(T, fullpath);
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonPushed(app, event)
            % this is where a function to play the video will be inserted
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            imshow(app.M(:,:,:,round(value)),'Parent',app.UIAxes); % this displays the selected frame in the original video axes
            imshow(app.L(:,:,:,round(value)),'Parent',app.UIAxes2); % this displays the selected frame in the processed video axes
            app.CurrentFrameEditField.Value = string(round(value)); % this sets the display in the Current Frame text field
            % to the value selected on the slider
            if  isnan(mean(app.cangle(:,round(value))))
                app.ContactAngleTxt.Value = -inf;
            else
                app.ContactAngleTxt.Value = mean(app.cangle(:,round(value)));
            end
            
            if isnan(app.fvelocity(4,1,round(value)))   %checking to see if the veloctiy at the 'value' frame is a real number
                app.InstantVelocityTxt.Value = -inf;
            else 
                app.InstantVelocityTxt.Value = app.fvelocity(4,1,round(value));
            end
            
            if isnan(app.fvelocity(4,2,round(value)))   %checking to see if the veloctiy at the 'value' frame is a real number
                app.DropletSpeedTxt.Value = -inf;
            else 
                app.DropletSpeedTxt.Value = app.fvelocity(4,2,round(value));
            end
            
            if isnan(mean(app.fvelocity(4,1,1:round(value)),"omitnan"))   %checking to see if the average velocity at the current frame is a real number
                app.AverageVelocityTxt.Value = -inf;
            else 
                app.AverageVelocityTxt.Value = mean(app.fvelocity(4,1,1:round(value)),"omitnan");
            end
            
            % app.DropletCountTxt.Value = app.totalobjects(round(value));
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            imshow(app.M(:,:,:,round(changingValue)),'Parent',app.UIAxes); % this displays the selected frame in the original video axes
            imshow(app.L(:,:,:,round(changingValue)),'Parent',app.UIAxes2); % this displays the selected frame in the processed video axes
            app.CurrentFrameEditField.Value = string(round(changingValue)); % this sets the display in the Current Frame text field
            % to the value selected on the slider
            if  isnan(mean(app.cangle(:,round(changingValue))))
                app.ContactAngleTxt.Value = -inf;
            else
                app.ContactAngleTxt.Value = mean(app.cangle(:,round(changingValue)));
            end
            
            if isnan(app.fvelocity(4,1,round(changingValue))) %checking to see if the veloctiy at the 'changingValue' frame is a real number
                app.InstantVelocityTxt.Value = -inf;
            else
                app.InstantVelocityTxt.Value = app.fvelocity(4,1,round(changingValue));
            end
            
            if isnan(app.fvelocity(4,2,round(changingValue))) %checking to see if the veloctiy at the 'changingValue' frame is a real number
                app.DropletSpeedTxt.Value = -inf;
            else
                app.DropletSpeedTxt.Value = app.fvelocity(4,2,round(changingValue));
            end
               
            if isnan(mean(app.fvelocity(4,1,1:round(changingValue)),"omitnan"))   %checking to see if the average velocity at the current frame is a real number
                app.AverageVelocityTxt.Value = -inf;
            else 
                app.AverageVelocityTxt.Value = mean(app.fvelocity(4,1,1:round(changingValue)),"omitnan");
            end
            
            % app.DropletCountTxt.Value = app.totalobjects(round(changingValue));
        end

        % Button pushed function: SaveProcessedVideoButton
        function SaveProcessedVideoButtonPushed(app, event)
            [filename,filepath]=uiputfile('.JPEG');
            [~,~,~,sizeapp] = size(app.L);
            i=1;
            while(i <= sizeapp)
                frame2file(app.L,filename,filepath,i);
                i=i+1;
            end
        end

        % Value changed function: PixelsmmEditField
        function PixelsmmEditFieldValueChanged(app, event)
            app.pxl_to_mm = app.PixelsmmEditField.Value;
        end

        % Button pushed function: ContactAngleDisplayButton
        function ContactAngleDisplayButtonPushed(app, event)
            if app.ContactAngleTxt.Visible == 1
               app.ContactAngleTxt.Visible = 'off'; % this hides the text box for contact angle given it was visible
            else 
               app.ContactAngleTxt.Visible = 'on'; % this displays the text box for contact angle given it was not visible
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadVideoButton = uibutton(app.UIFigure, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'Upload Video';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [0 15];
            app.FrameSlider.MajorTicks = [5 10 15];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.SaveProcessedVideoButton = uibutton(app.UIFigure, 'push');
            app.SaveProcessedVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedVideoButtonPushed, true);
            app.SaveProcessedVideoButton.Text = 'Save Processed Video ';
            app.DisplayBoundaryLayerButton = uibutton(app.UIFigure, 'state');
            app.DisplayBoundaryLayerButton.Text = {'Display Boundary'; 'Layer'};
            app.InstantaneousVelocityDisplayButton = uibutton(app.UIFigure, 'push');
            app.InstantaneousVelocityDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @InstantaneousVelocityDisplayButtonPushed, true);
            app.InstantaneousVelocityDisplayButton.Text = 'Instantaneous Velocity Display';
            app.AverageVelocityDisplayButton = uibutton(app.UIFigure, 'push');
            app.AverageVelocityDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @AverageVelocityDisplayButtonPushed, true);
            app.AverageVelocityDisplayButton.Text = 'Average Velocity Display';
            app.MaxSpreadRadiusDisplayButton = uibutton(app.UIFigure, 'push');
            app.MaxSpreadRadiusDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @MaxSpreadRadiusDisplayButtonPushed, true);
            app.MaxSpreadRadiusDisplayButton.Text = 'Max Spread Radius Display';
            app.SaveAnalyticsButton = uibutton(app.UIFigure, 'push');
            app.SaveAnalyticsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalyticsButtonPushed, true);
            app.SaveAnalyticsButton.Text = 'Save Analytics';
            app.SatelliteDropletSpeedDisplayButton = uibutton(app.UIFigure, 'push');
            app.SatelliteDropletSpeedDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @SatelliteDropletSpeedDisplayButtonPushed, true);
            app.SatelliteDropletSpeedDisplayButton.Text = 'Satellite Droplet Speed Display';
            app.SatelliteDropletCountDisplayButton = uibutton(app.UIFigure, 'push');
            app.SatelliteDropletCountDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @SatelliteDropletCountDisplayButtonPushed, true);
            app.SatelliteDropletCountDisplayButton.Text = 'Satellite Droplet Count Display';
            app.PlayPauseButton = uibutton(app.UIFigure, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonPushed, true);
            app.PlayPauseButton.Text = 'Play/Pause';
            app.InstantaneousVelocityUnits = uieditfield(app.UIFigure, 'text');
            app.InstantaneousVelocityUnits.Editable = 'off';
            app.InstantaneousVelocityUnits.Value = 'mm/s';
            app.AverageVelocityUnits = uieditfield(app.UIFigure, 'text');
            app.AverageVelocityUnits.Editable = 'off';
            app.AverageVelocityUnits.Value = 'mm/s';
            app.MaxSpreadUnits = uieditfield(app.UIFigure, 'text');
            app.MaxSpreadUnits.Editable = 'off';
            app.MaxSpreadUnits.Value = 'mm';
            app.DropletSpeedUnits = uieditfield(app.UIFigure, 'text');
            app.DropletSpeedUnits.Editable = 'off';
            app.DropletSpeedUnits.Value = 'mm/s';
            app.DropletCountUnits = uieditfield(app.UIFigure, 'text');
            app.DropletCountUnits.Editable = 'off';
            app.DropletCountUnits.Value = 'droplets';
            app.CurrentFrameEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrameEditFieldLabel.Text = 'Current Frame';
            app.CurrentFrameEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentFrameEditField.Editable = 'off';
            app.CurrentFrameEditField.Value = '#';
            app.InstantVelocityTxt = uieditfield(app.UIFigure, 'numeric');
            app.InstantVelocityTxt.Visible = 'off';
            app.AverageVelocityTxt = uieditfield(app.UIFigure, 'numeric');
            app.AverageVelocityTxt.Visible = 'off';
            app.MaxRadiusTxt = uieditfield(app.UIFigure, 'numeric');
            app.MaxRadiusTxt.Visible = 'off';
            app.DropletSpeedTxt = uieditfield(app.UIFigure, 'numeric');
            app.DropletSpeedTxt.Visible = 'off';
            app.DropletCountTxt = uieditfield(app.UIFigure, 'numeric');
            app.DropletCountTxt.Visible = 'off';
            app.PixelsmmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsmmEditFieldLabel.Text = 'Pixels/mm';
            app.PixelsmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsmmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsmmEditFieldValueChanged, true);
            app.ContactAngleDisplayButton = uibutton(app.UIFigure, 'push');
            app.ContactAngleDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @ContactAngleDisplayButtonPushed, true);
            app.ContactAngleDisplayButton.Text = 'Contact Angle Display';
            app.ContactAngleUnits = uieditfield(app.UIFigure, 'text');
            app.ContactAngleUnits.Editable = 'off';
            app.ContactAngleUnits.Value = 'degrees';
            app.ContactAngleTxt = uieditfield(app.UIFigure, 'numeric');
            app.ContactAngleTxt.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Video')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Processed Video')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end