classdef floorselectdlgbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FloorHeightSliderLabel     matlab.ui.control.Label
        FloorHeightSlider          matlab.ui.control.Slider
        FloorAngleSliderLabel      matlab.ui.control.Label
        FloorAngleSlider           matlab.ui.control.Slider
        SetFloorHeightButton       matlab.ui.control.Button
        FloorAngleEditFieldLabel   matlab.ui.control.Label
        FloorAngleEditField        matlab.ui.control.NumericEditField
        FloorHeightEditFieldLabel  matlab.ui.control.Label
        FloorHeightEditField       matlab.ui.control.NumericEditField
        LineColorDropDownLabel     matlab.ui.control.Label
        LineColorDropDown          matlab.ui.control.DropDown
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app object
        currentheight % pixel height of line
        currentangle % angle of line
        F % matrix of frames for floor
        W % all pixels in x direction of frame
        a % height of frame
        b % width of frame
        color % color of the plotted line
        y % function for line
        roi % user-created region of interest
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, M)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.F = M;
            
            imshow(app.F(:,:,:,round(end/2)),'Parent',app.UIAxes); % this displays the input file in the original video axes
            hold(app.UIAxes,"on");
            [app.a,app.b,c,d] = size(app.F); % this stores the total height and width of the frames of M
            
            % initializing the line at halfway through the image with an
            % angle of 0
            app.W = [1:app.b];
            app.currentangle = 0;
            app.currentheight = 0;
            app.y = tan(deg2rad(app.currentangle))*-(app.W-(app.b/2));
            app.color = 'b';
            plot(app.UIAxes,app.W,app.y,app.color);
            xlim(app.UIAxes,[1 app.b]);
            ylim(app.UIAxes,[1 app.a]);
            hold(app.UIAxes,"off");
            
            % initializing the slider for floor height
            maxheight = app.a; % this sets the max height using the size of the video matrix
            app.FloorHeightSlider.Limits = [0 maxheight]; % this sets the slider limits to 1 to the pixel height of the frame
            app.FloorHeightSlider.MajorTicks = [0:50:maxheight]; % this sets the slider major ticks to the pixel height of the frame
            % in increments of 50
            app.FloorHeightSlider.MinorTicks = [0:5:maxheight]; % this sets the slider minor ticks to the pixel height of the frame
            % in increments of 25
           
        end

        % Value changed function: FloorHeightSlider
        function FloorHeightSliderValueChanged(app, event)
            app.currentheight = round(app.FloorHeightSlider.Value);
            app.y = tan(deg2rad(app.currentangle))*-(app.W-(app.W(end)/2)) + app.currentheight;
            imshow(app.F(:,:,:,round(end/2)),'Parent',app.UIAxes); % this displays the input file in the original video axes
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.W,app.y,app.color);
            xlim(app.UIAxes,[1 app.b]);
            ylim(app.UIAxes,[1 app.a]);
            hold(app.UIAxes, "off");
            app.FloorHeightEditField.Value = app.currentheight; % this sets the display in the Floor Height text field
            % to the value selected on the slider
        end

        % Value changing function: FloorHeightSlider
        function FloorHeightSliderValueChanging(app, event)
            app.currentheight = round(event.Value);
            app.y = tan(deg2rad(app.currentangle))*-(app.W-(app.W(end)/2)) + app.currentheight;
            imshow(app.F(:,:,:,round(end/2)),'Parent',app.UIAxes); % this displays the input file in the original video axes
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.W,app.y,app.color);
            xlim(app.UIAxes,[1 app.b]);
            ylim(app.UIAxes,[1 app.a]);
            hold(app.UIAxes, "off");
            app.FloorHeightEditField.Value = round(app.currentheight); % this sets the display in the Floor Height text field
            % to the value selected on the slider
        end

        % Value changed function: FloorHeightEditField
        function FloorHeightEditFieldValueChanged(app, event)
            app.currentheight = round(app.FloorHeightEditField.Value);
            app.y = tan(deg2rad(app.currentangle))*-(app.W-(app.W(end)/2)) + app.currentheight;
            imshow(app.F(:,:,:,round(end/2)),'Parent',app.UIAxes); % this displays the input file in the original video axes
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.W,app.y,app.color);
            xlim(app.UIAxes,[1 app.b]);
            ylim(app.UIAxes,[1 app.a]);
            hold(app.UIAxes, "off");
        end

        % Value changed function: FloorAngleSlider
        function FloorAngleSliderValueChanged(app, event)
            app.currentangle = app.FloorAngleSlider.Value;
            app.y = tan(deg2rad(app.currentangle))*-(app.W-(app.W(end)/2)) + app.currentheight;
            imshow(app.F(:,:,:,round(end/2)),'Parent',app.UIAxes); % this displays the input file in the original video axes
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.W,app.y,app.color);
            xlim(app.UIAxes,[1 app.b]);
            ylim(app.UIAxes,[1 app.a]);
            hold(app.UIAxes, "off");
            app.FloorAngleEditField.Value = app.currentangle; % this sets the display in the Floor Angle text field
            % to the value selected on the slider
        end

        % Value changing function: FloorAngleSlider
        function FloorAngleSliderValueChanging(app, event)
            app.currentangle = event.Value;
            app.y = tan(deg2rad(app.currentangle))*-(app.W-(app.W(end)/2)) + app.currentheight;
            imshow(app.F(:,:,:,round(end/2)),'Parent',app.UIAxes); % this displays the input file in the original video axes
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.W,app.y,app.color);
            xlim(app.UIAxes,[1 app.b]);
            ylim(app.UIAxes,[1 app.a]);
            hold(app.UIAxes, "off");
            app.FloorAngleEditField.Value = app.currentangle; % this sets the display in the Floor Angle text field
            % to the value selected on the slider
        end

        % Value changed function: FloorAngleEditField
        function FloorAngleEditFieldValueChanged(app, event)
            app.currentangle = app.FloorAngleEditField.Value;
            app.y = tan(deg2rad(app.currentangle))*-(app.W-(app.W(end)/2)) + app.currentheight;
            imshow(app.F(:,:,:,round(end/2)),'Parent',app.UIAxes); % this displays the input file in the original video axes
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.W,app.y,app.color);
            xlim(app.UIAxes,[1 app.b]);
            ylim(app.UIAxes,[1 app.a]);
            hold(app.UIAxes, "off");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % this sets the floor height and angle in the main app
            updatefloor(app.CallingApp, app.currentheight, app.currentangle);
            
            % this closes the app
            delete(app)
        end

        % Button pushed function: SetFloorHeightButton
        function SetFloorHeightButtonPushed(app, event)
             % this sets the floor height and angle in the main app
            updatefloor(app.CallingApp, app.currentheight, app.currentangle);
            
            % this closes the app
            delete(app)
        end

        % Value changed function: LineColorDropDown
        function LineColorDropDownValueChanged(app, event)
            value = app.LineColorDropDown.Value;
            if strcmp(value,'Blue')
                app.color = 'b';
            elseif strcmp(value,'Green')
                app.color = 'g';
            elseif strcmp(value,'Red')
                app.color = 'r';
            elseif strcmp(value,'Yellow')
                app.color = 'y';
            end
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.W,app.y,app.color);
            xlim(app.UIAxes,[1 app.b]);
            ylim(app.UIAxes,[1 app.a]);
            hold(app.UIAxes, "off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FloorHeightSliderLabel = uilabel(app.UIFigure);
            app.FloorHeightSliderLabel.Text = 'Floor Height';
            app.FloorHeightSlider = uislider(app.UIFigure);
            app.FloorHeightSlider.ValueChangedFcn = createCallbackFcn(app, @FloorHeightSliderValueChanged, true);
            app.FloorHeightSlider.ValueChangingFcn = createCallbackFcn(app, @FloorHeightSliderValueChanging, true);
            app.FloorAngleSliderLabel = uilabel(app.UIFigure);
            app.FloorAngleSliderLabel.Text = 'Floor Angle';
            app.FloorAngleSlider = uislider(app.UIFigure);
            app.FloorAngleSlider.Limits = [-45 45];
            app.FloorAngleSlider.MajorTicks = [-45 -30 -15 0 15 30 45];
            app.FloorAngleSlider.ValueChangedFcn = createCallbackFcn(app, @FloorAngleSliderValueChanged, true);
            app.FloorAngleSlider.ValueChangingFcn = createCallbackFcn(app, @FloorAngleSliderValueChanging, true);
            app.FloorAngleSlider.MinorTicks = [-45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45];
            app.SetFloorHeightButton = uibutton(app.UIFigure, 'push');
            app.SetFloorHeightButton.ButtonPushedFcn = createCallbackFcn(app, @SetFloorHeightButtonPushed, true);
            app.SetFloorHeightButton.Text = 'Set Floor Height';
            app.FloorAngleEditFieldLabel = uilabel(app.UIFigure);
            app.FloorAngleEditFieldLabel.Text = 'Floor Angle';
            app.FloorAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.FloorAngleEditField.ValueChangedFcn = createCallbackFcn(app, @FloorAngleEditFieldValueChanged, true);
            app.FloorHeightEditFieldLabel = uilabel(app.UIFigure);
            app.FloorHeightEditFieldLabel.Text = 'Floor Height';
            app.FloorHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.FloorHeightEditField.ValueChangedFcn = createCallbackFcn(app, @FloorHeightEditFieldValueChanged, true);
            app.LineColorDropDownLabel = uilabel(app.UIFigure);
            app.LineColorDropDownLabel.Text = 'Line Color';
            app.LineColorDropDown = uidropdown(app.UIFigure);
            app.LineColorDropDown.Items = {'Blue', 'Green', 'Red', 'Yellow'};
            app.LineColorDropDown.ValueChangedFcn = createCallbackFcn(app, @LineColorDropDownValueChanged, true);
            app.LineColorDropDown.Value = 'Blue';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Video')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = floorselectdlgbox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end